classdef turbine_cal_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TestNameEditField               matlab.ui.control.EditField
        TestNameEditFieldLabel          matlab.ui.control.Label
        LoadDirectionEditField          matlab.ui.control.EditField
        LoadDirectionEditFieldLabel     matlab.ui.control.Label
        MetaDataValue1EditField_4       matlab.ui.control.EditField
        MetaDataValue1EditFieldLabel_4  matlab.ui.control.Label
        MetaDataName1EditField_4        matlab.ui.control.EditField
        MetaDataName1EditFieldLabel_4   matlab.ui.control.Label
        MetaDataValue1EditField_3       matlab.ui.control.EditField
        MetaDataValue1EditFieldLabel_3  matlab.ui.control.Label
        MetaDataName1EditField_3        matlab.ui.control.EditField
        MetaDataName1EditFieldLabel_3   matlab.ui.control.Label
        MetaDataValue1EditField_2       matlab.ui.control.EditField
        MetaDataValue1EditFieldLabel_2  matlab.ui.control.Label
        MetaDataName1EditField_2        matlab.ui.control.EditField
        MetaDataName1EditFieldLabel_2   matlab.ui.control.Label
        ClearandRelaunchDAQButton       matlab.ui.control.Button
        CurrentSaveDirectoryLabel       matlab.ui.control.Label
        ChangeSaveFolderButton          matlab.ui.control.Button
        MetaDataValue1EditField         matlab.ui.control.EditField
        MetaDataValue1EditFieldLabel    matlab.ui.control.Label
        MetaDataName1EditField          matlab.ui.control.EditField
        MetaDataName1EditFieldLabel     matlab.ui.control.Label
        ApproxWindspeedEditField        matlab.ui.control.EditField
        ApproxWindspeedEditFieldLabel   matlab.ui.control.Label
        AppliedLoadlbsEditField         matlab.ui.control.EditField
        AppliedLoadlbsEditFieldLabel    matlab.ui.control.Label
        CollectDataButton               matlab.ui.control.Button
    end

    properties
    metaData = struct;
    d = xfedaq();
    t = timer;
    end
    
    methods (Access = private)
        
        function changeButtonBack(app,~,~)
            app.CollectDataButton.BackgroundColor = [234, 234, 234]./255;
            app.CollectDataButton.Text = "Collect Data";
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            xfedaqSetUpMATLABPaths;
            app.d = setupDAQs;
            app.t.TimerFcn = @(~,~)app.changeButtonBack;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            for i = 1:length(app.d.task)
                try
                    app.d.task(i).stopTask();
                catch
                end
            end
            app.d.delete;
            delete(app);
        end

        % Button pushed function: CollectDataButton
        function CollectDataButtonPushed(app, event)
            app.d.start();
            app.t.StartDelay = app.d.durationSeconds + 2;
            app.CollectDataButton.BackgroundColor = [255, 67, 67]./255;
            app.CollectDataButton.Text = "Collecting...";
            app.t.start;
        end

        % Value changed function: TestNameEditField
        function TestNameEditFieldValueChanged(app, event)
            value = app.TestNameEditField.Value;
            app.d.task(1).metaData.testName = value;
        end

        % Value changed function: LoadDirectionEditField
        function LoadDirectionEditFieldValueChanged(app, event)
            value = app.LoadDirectionEditField.Value;
            app.d.task(1).metaData.loadDirection = value;
        end

        % Value changed function: AppliedLoadlbsEditField
        function AppliedLoadlbsEditFieldValueChanged(app, event)
            value = app.AppliedLoadlbsEditField.Value;
            app.d.task(1).metaData.appliedLoad = value;
        end

        % Value changed function: ApproxWindspeedEditField
        function ApproxWindspeedEditFieldValueChanged(app, event)
            value = app.ApproxWindspeedEditField.Value;
            app.d.task(1).metaData.approxWindSpeed = value;
        end

        % Value changed function: MetaDataName1EditField
        function MetaDataName1EditFieldValueChanged(app, event)
            value = app.MetaDataName1EditField.Value;
            app.metaData(1).name = value;
        end

        % Value changed function: MetaDataValue1EditField
        function MetaDataValue1EditFieldValueChanged(app, event)
            value = app.MetaDataValue1EditField.Value;
            app.metaData(1).value = value;
            if isfield(app.metaData(1),'name') && ~isempty(app.metaData(1).name)
                app.d.task(1).metaData.(app.metaData(1).name) = app.metaData(1).value;
            end
        end

        % Value changed function: MetaDataName1EditField_2
        function MetaDataName1EditField_2ValueChanged(app, event)
            value = app.MetaDataName1EditField_2.Value;
            app.metaData(2).name = value;
        end

        % Value changed function: MetaDataValue1EditField_2
        function MetaDataValue1EditField_2ValueChanged(app, event)
            value = app.MetaDataValue1EditField_2.Value;
            app.metaData(2).value = value;
            if isfield(app.metaData(2),'name') && ~isempty(app.metaData(2).name)
                app.d.task(1).metaData.(app.metaData(2).name) = app.metaData(2).value;
            end
        end

        % Value changed function: MetaDataName1EditField_3
        function MetaDataName1EditField_3ValueChanged(app, event)
            value = app.MetaDataName1EditField_3.Value;
            app.metaData(3).name = value;
        end

        % Value changed function: MetaDataValue1EditField_3
        function MetaDataValue1EditField_3ValueChanged(app, event)
            value = app.MetaDataValue1EditField_3.Value;
            app.metaData(3).value = value;
            if isfield(app.metaData(3),'name') && ~isempty(app.metaData(3).name)
                app.d.task(1).metaData.(app.metaData(3).name) = app.metaData(3).value;
            end
        end

        % Value changed function: MetaDataName1EditField_4
        function MetaDataName1EditField_4ValueChanged(app, event)
            value = app.MetaDataName1EditField_4.Value;
            app.metaData(4).name = value;
        end

        % Value changed function: MetaDataValue1EditField_4
        function MetaDataValue1EditField_4ValueChanged(app, event)
            value = app.MetaDataValue1EditField_4.Value;
            app.metaData(4).value = value;
            if isfield(app.metaData(4),'name') && ~isempty(app.metaData(4).name)
                app.d.task(1).metaData.(app.metaData(4).name) = app.metaData(4).value;
            end
        end

        % Button pushed function: ChangeSaveFolderButton
        function ChangeSaveFolderButtonPushed(app, event)
            app.d.logging.directoryPath = uigetdir;
            app.d.configureLogging();
            app.CurrentSaveDirectoryLabel.Text = app.d.logging.directoryPath;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CollectDataButton = uibutton(app.UIFigure, 'push');
            app.CollectDataButton.ButtonPushedFcn = createCallbackFcn(app, @CollectDataButtonPushed, true);
            app.CollectDataButton.Text = 'Collect Data';
            app.AppliedLoadlbsEditFieldLabel = uilabel(app.UIFigure);
            app.AppliedLoadlbsEditFieldLabel.Text = 'Applied Load (lbs)';
            app.AppliedLoadlbsEditField = uieditfield(app.UIFigure, 'text');
            app.AppliedLoadlbsEditField.ValueChangedFcn = createCallbackFcn(app, @AppliedLoadlbsEditFieldValueChanged, true);
            app.ApproxWindspeedEditFieldLabel = uilabel(app.UIFigure);
            app.ApproxWindspeedEditFieldLabel.Text = 'Approx Windspeed';
            app.ApproxWindspeedEditField = uieditfield(app.UIFigure, 'text');
            app.ApproxWindspeedEditField.ValueChangedFcn = createCallbackFcn(app, @ApproxWindspeedEditFieldValueChanged, true);
            app.MetaDataName1EditFieldLabel = uilabel(app.UIFigure);
            app.MetaDataName1EditFieldLabel.Text = 'Meta Data Name 1';
            app.MetaDataName1EditField = uieditfield(app.UIFigure, 'text');
            app.MetaDataName1EditField.ValueChangedFcn = createCallbackFcn(app, @MetaDataName1EditFieldValueChanged, true);
            app.MetaDataValue1EditFieldLabel = uilabel(app.UIFigure);
            app.MetaDataValue1EditFieldLabel.Text = 'Meta Data Value 1';
            app.MetaDataValue1EditField = uieditfield(app.UIFigure, 'text');
            app.MetaDataValue1EditField.ValueChangedFcn = createCallbackFcn(app, @MetaDataValue1EditFieldValueChanged, true);
            app.ChangeSaveFolderButton = uibutton(app.UIFigure, 'push');
            app.ChangeSaveFolderButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeSaveFolderButtonPushed, true);
            app.ChangeSaveFolderButton.Text = 'Change Save Folder';
            app.CurrentSaveDirectoryLabel = uilabel(app.UIFigure);
            app.CurrentSaveDirectoryLabel.Text = 'Current/Save/Directory';
            app.ClearandRelaunchDAQButton = uibutton(app.UIFigure, 'push');
            app.ClearandRelaunchDAQButton.Text = {'Clear and '; 'Relaunch DAQ'};
            app.MetaDataName1EditFieldLabel_2 = uilabel(app.UIFigure);
            app.MetaDataName1EditFieldLabel_2.Text = 'Meta Data Name 2';
            app.MetaDataName1EditField_2 = uieditfield(app.UIFigure, 'text');
            app.MetaDataName1EditField_2.ValueChangedFcn = createCallbackFcn(app, @MetaDataName1EditField_2ValueChanged, true);
            app.MetaDataValue1EditFieldLabel_2 = uilabel(app.UIFigure);
            app.MetaDataValue1EditFieldLabel_2.Text = 'Meta Data Value 2';
            app.MetaDataValue1EditField_2 = uieditfield(app.UIFigure, 'text');
            app.MetaDataValue1EditField_2.ValueChangedFcn = createCallbackFcn(app, @MetaDataValue1EditField_2ValueChanged, true);
            app.MetaDataName1EditFieldLabel_3 = uilabel(app.UIFigure);
            app.MetaDataName1EditFieldLabel_3.Text = 'Meta Data Name 3';
            app.MetaDataName1EditField_3 = uieditfield(app.UIFigure, 'text');
            app.MetaDataName1EditField_3.ValueChangedFcn = createCallbackFcn(app, @MetaDataName1EditField_3ValueChanged, true);
            app.MetaDataValue1EditFieldLabel_3 = uilabel(app.UIFigure);
            app.MetaDataValue1EditFieldLabel_3.Text = 'Meta Data Value 3';
            app.MetaDataValue1EditField_3 = uieditfield(app.UIFigure, 'text');
            app.MetaDataValue1EditField_3.ValueChangedFcn = createCallbackFcn(app, @MetaDataValue1EditField_3ValueChanged, true);
            app.MetaDataName1EditFieldLabel_4 = uilabel(app.UIFigure);
            app.MetaDataName1EditFieldLabel_4.Text = 'Meta Data Name 4';
            app.MetaDataName1EditField_4 = uieditfield(app.UIFigure, 'text');
            app.MetaDataName1EditField_4.ValueChangedFcn = createCallbackFcn(app, @MetaDataName1EditField_4ValueChanged, true);
            app.MetaDataValue1EditFieldLabel_4 = uilabel(app.UIFigure);
            app.MetaDataValue1EditFieldLabel_4.Text = 'Meta Data Value 4';
            app.MetaDataValue1EditField_4 = uieditfield(app.UIFigure, 'text');
            app.MetaDataValue1EditField_4.ValueChangedFcn = createCallbackFcn(app, @MetaDataValue1EditField_4ValueChanged, true);
            app.LoadDirectionEditFieldLabel = uilabel(app.UIFigure);
            app.LoadDirectionEditFieldLabel.Text = 'Load Direction';
            app.LoadDirectionEditField = uieditfield(app.UIFigure, 'text');
            app.LoadDirectionEditField.ValueChangedFcn = createCallbackFcn(app, @LoadDirectionEditFieldValueChanged, true);
            app.TestNameEditFieldLabel = uilabel(app.UIFigure);
            app.TestNameEditFieldLabel.Text = 'Test Name';
            app.TestNameEditField = uieditfield(app.UIFigure, 'text');
            app.TestNameEditField.ValueChangedFcn = createCallbackFcn(app, @TestNameEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = turbine_cal_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end